classdef integrar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Panel_3                       matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        Panel_2                       matlab.ui.container.Panel
        integrarLabel                 matlab.ui.control.Label
        Button                        matlab.ui.control.Button
        ConocermasButton              matlab.ui.control.Button
        MtodosparaLabel               matlab.ui.control.Label
        IntroducelafuncionEditField   matlab.ui.control.EditField
        IntroducelafuncionEditFieldLabel  matlab.ui.control.Label
        CalcularButton                matlab.ui.control.Button
        PuntodeterminoEditField       matlab.ui.control.NumericEditField
        PuntodeterminoEditFieldLabel  matlab.ui.control.Label
        PuntodeinicioEditField        matlab.ui.control.NumericEditField
        PuntodeinicioEditFieldLabel   matlab.ui.control.Label
        SeleccionaelmetodoDropDown    matlab.ui.control.DropDown
        SeleccionaelmetodoDropDownLabel  matlab.ui.control.Label
        ValordenEditField             matlab.ui.control.NumericEditField
        ValordenEditFieldLabel        matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, metodo)
            app.SeleccionaelmetodoDropDown.Value = metodo;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            funcion = str2sym(app.IntroducelafuncionEditField.Value);
            a = app.PuntodeinicioEditField.Value;
            b = app.PuntodeterminoEditField.Value;
            n = app.ValordenEditField.Value;
            tipo_de_metodo = app.SeleccionaelmetodoDropDown.Value;
            switch tipo_de_metodo
                case "Punto medio compuesto"
                    resultados = puntomediocompuesto(a,b,funcion,n);
                case "Punto medio simple"
                    resultados = puntomediosimple(a,b,funcion);
                case "Trapecio simple"
                    resultados = trapeciosimple(a,b,funcion);
                case "Trapecio compuesto"
                    resultados = trapeciocompuesto(a,b,funcion,n);
                case "Simpson 3/8 simple"
                    resultados = simpson3_8simple(a,b,funcion);
                case "Simpson 3/8 compuesto"
                    resultados = simpson3_8compuesto(a,b,funcion,n);
                case "Simpson 1/3 simple"
                    resultados = simpson1_3simple(a,b,funcion);
                case "Simpson 1/3 compuesto"
                    resultados = simpson1_3compuesto(a,b,funcion,n);
            end
            app.UITable.ColumnFormat =  {'short','long','long'};
            app.UITable.Data = resultados;


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Funcion vs Interpolacion')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.ValordenEditFieldLabel = uilabel(app.Panel_2);
            app.ValordenEditFieldLabel.Text = 'Valor de n';
            app.ValordenEditField = uieditfield(app.Panel_2, 'numeric');
            app.SeleccionaelmetodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmetodoDropDownLabel.Text = 'Selecciona el metodo';
            app.SeleccionaelmetodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmetodoDropDown.Items = {'Punto medio compuesto', 'Punto medio simple', 'Trapecio simple', 'Trapecio compuesto', 'Simpson 3/8 simple', 'Simpson 3/8 compuesto', 'Simpson 1/3 simple', 'Simpson 1/3 compuesto'};
            app.SeleccionaelmetodoDropDown.Value = 'Punto medio compuesto';
            app.PuntodeinicioEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeinicioEditFieldLabel.Text = 'Punto de inicio';
            app.PuntodeinicioEditField = uieditfield(app.Panel_2, 'numeric');
            app.PuntodeterminoEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeterminoEditFieldLabel.Text = 'Punto de termino';
            app.PuntodeterminoEditField = uieditfield(app.Panel_2, 'numeric');
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncionEditFieldLabel = uilabel(app.Panel_2);
            app.IntroducelafuncionEditFieldLabel.Text = 'Introduce la funcion';
            app.IntroducelafuncionEditField = uieditfield(app.Panel_2, 'text');
            app.MtodosparaLabel = uilabel(app.Panel_2);
            app.MtodosparaLabel.Text = 'Métodos para';
            app.ConocermasButton = uibutton(app.Panel_2, 'push');
            app.ConocermasButton.Text = 'Conocer mas...';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Text = '';
            app.integrarLabel = uilabel(app.Panel_2);
            app.integrarLabel.Text = 'integrar';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'Iteracion'; 'x'; 'y'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = integrar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end