classdef resolver_ode < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_3                         matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        Panel_2                         matlab.ui.container.Panel
        GuardarresultadosButton         matlab.ui.control.Button
        EjemploresueltoButton           matlab.ui.control.Button
        Button_2                        matlab.ui.control.Button
        Button                          matlab.ui.control.Button
        ConocermasButton                matlab.ui.control.Button
        MtodosparaEDOLabel              matlab.ui.control.Label
        IntroducelafuncionEditField     matlab.ui.control.EditField
        IntroducelafuncionEditFieldLabel  matlab.ui.control.Label
        CalcularButton                  matlab.ui.control.Button
        PuntodeterminoEditField         matlab.ui.control.NumericEditField
        PuntodeterminoEditFieldLabel    matlab.ui.control.Label
        PuntodeinicioEditField          matlab.ui.control.NumericEditField
        PuntodeinicioEditFieldLabel     matlab.ui.control.Label
        SeleccionaelmetodoDropDown      matlab.ui.control.DropDown
        SeleccionaelmetodoDropDownLabel  matlab.ui.control.Label
        TamaodehonEditField             matlab.ui.control.NumericEditField
        TamaodehonEditFieldLabel        matlab.ui.control.Label
        CondicionInicialEditField       matlab.ui.control.NumericEditField
        CondicionInicialEditFieldLabel  matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            inicio
            delete(app)
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
             textofuncion = app.IntroducelafuncionEditField.Value; % se obtiene el valor del campo funcion
                % todo este bloque es por si el usuario ingreso una funcion
                % anonima se valida si tiene el caracter de @ y si no lo
                % inlcuyo se generara una funcion simbolica que despues se
                % pasara a anonima para asi evitar errores
            if contains(textofuncion,"@")
                funcion = str2func(textofuncion);
            elseif ~contains(textofuncion,"@")
                funcion = matlabFunction(str2sym(textofuncion));
            end
            y0 = app.CondicionInicialEditField.Value; % obtener condicion inicial
            b = app.PuntodeterminoEditField.Value; % obtener punto final
            a = app.PuntodeinicioEditField.Value; % obtener punto inicial
            t = app.TamaodehonEditField.Value; % obtener ya sea el tamaño del paso o el numero de elementos
            if t < 1 % si es menor a 1 es un paso
                n = round((b-a)/t); % se convierte a numero de elementos
            else
                n = t; % se deja tal como estaba
            end
            metodo = app.SeleccionaelmetodoDropDown.Value;
            % casos para llamar al metodo que haya elegido el usuario
            switch metodo
                case "Euler"
                    sol = eulerODE(funcion,a,b,y0,n);
                case "Taylor orden 2"
                    sol = taylorOrden2ODE(funcion,a,b,y0,n);
                case "Taylor orden 3"
                    sol = taylorOrden3ODE(funcion,a,b,y0,n);
                case "Runge-Kutta 2"
                    sol = rungeKutta2(funcion,a,b,y0,n);
                case "Runge-Kutta 4"
                    sol = rungeKutta4(funcion,a,b,y0,n);
            end
            plot(app.UIAxes,sol(:,1),sol(:,2)) % graficar
            app.UITable.ColumnFormat = {'short','long','long'};
            largo = size(sol);
            l = 1:largo;
            app.UITable.Data = [l' sol];
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
              opc=questdlg('¿Desea salir del programa?','SALIR','Sí','No','No');
            if strcmp(opc,'No')
                return;
            end
            delete(app)
            
            
        end

        % Button pushed function: EjemploresueltoButton
        function EjemploresueltoButtonPushed(app, event)
            % funcion para el boton ejemplo resuelto
            funcion = @(x,y) x.^2-y.^2-cos(x).*y;
            app.IntroducelafuncionEditField.Value = "@(x,y) x.^2-y.^2-cos(x).*y";
            app.CondicionInicialEditField.Value = 1;
            app.PuntodeinicioEditField.Value = 0;
            app.PuntodeterminoEditField.Value = 3;
            app.TamaodehonEditField.Value = 200;
            app.SeleccionaelmetodoDropDown.Value = "Runge-Kutta 4";
            sol = rungeKutta4(funcion,0,3,1,200);
            plot(app.UIAxes,sol(:,1),sol(:,2)) % graficar
            app.UITable.ColumnFormat = {'short','long','long'};
            largo = size(sol);
            l = 1:largo;
            app.UITable.Data = [l' sol];
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            % funcion para el boton de ayuda
        end

        % Button pushed function: GuardarresultadosButton
        function GuardarresultadosButtonPushed(app, event)
         
          
            writematrix(app.UITable.Data,'resultadosODE.csv')
            msgbox("Los datos se han guardado con exito")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Función')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.Panel_2 = uipanel(app.UIFigure);
            app.CondicionInicialEditFieldLabel = uilabel(app.Panel_2);
            app.CondicionInicialEditFieldLabel.Text = 'Condicion Inicial';
            app.CondicionInicialEditField = uieditfield(app.Panel_2, 'numeric');
            app.TamaodehonEditFieldLabel = uilabel(app.Panel_2);
            app.TamaodehonEditFieldLabel.Text = 'Tamaño de h o n';
            app.TamaodehonEditField = uieditfield(app.Panel_2, 'numeric');
            app.SeleccionaelmetodoDropDownLabel = uilabel(app.Panel_2);
            app.SeleccionaelmetodoDropDownLabel.Text = 'Selecciona el metodo';
            app.SeleccionaelmetodoDropDown = uidropdown(app.Panel_2);
            app.SeleccionaelmetodoDropDown.Items = {'Euler', 'Taylor orden 2', 'Taylor orden 3', 'Runge-Kutta 2', 'Runge-Kutta 4'};
            app.SeleccionaelmetodoDropDown.Value = 'Euler';
            app.PuntodeinicioEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeinicioEditFieldLabel.Text = 'Punto de inicio';
            app.PuntodeinicioEditField = uieditfield(app.Panel_2, 'numeric');
            app.PuntodeterminoEditFieldLabel = uilabel(app.Panel_2);
            app.PuntodeterminoEditFieldLabel.Text = 'Punto de termino';
            app.PuntodeterminoEditField = uieditfield(app.Panel_2, 'numeric');
            app.CalcularButton = uibutton(app.Panel_2, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Tooltip = {'Boton para calcular'};
            app.CalcularButton.Text = 'Calcular';
            app.IntroducelafuncionEditFieldLabel = uilabel(app.Panel_2);
            app.IntroducelafuncionEditFieldLabel.Text = 'Introduce la funcion';
            app.IntroducelafuncionEditField = uieditfield(app.Panel_2, 'text');
            app.MtodosparaEDOLabel = uilabel(app.Panel_2);
            app.MtodosparaEDOLabel.Text = 'Métodos para EDO';
            app.ConocermasButton = uibutton(app.Panel_2, 'push');
            app.ConocermasButton.Tooltip = {'Boton para acceder a la teoria del metodo seleccionado'};
            app.ConocermasButton.Text = 'Conocer mas...';
            app.Button = uibutton(app.Panel_2, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon awesome-home.svg');
            app.Button.Tooltip = {'Regresar al inicio'};
            app.Button.Text = '';
            app.Button_2 = uibutton(app.Panel_2, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'recursos', 'Icon feather-help-circle@2x.png');
            app.Button_2.Tooltip = {'Boton de ayuda'};
            app.Button_2.Text = '';
            app.EjemploresueltoButton = uibutton(app.Panel_2, 'push');
            app.EjemploresueltoButton.ButtonPushedFcn = createCallbackFcn(app, @EjemploresueltoButtonPushed, true);
            app.EjemploresueltoButton.Tooltip = {'Boton que rellena todos los campos con un ejemplo'};
            app.EjemploresueltoButton.Text = 'Ejemplo resuelto';
            app.GuardarresultadosButton = uibutton(app.Panel_2, 'push');
            app.GuardarresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarresultadosButtonPushed, true);
            app.GuardarresultadosButton.Text = 'Guardar resultados';
            app.Panel_3 = uipanel(app.UIFigure);
            app.UITable = uitable(app.Panel_3);
            app.UITable.ColumnName = {'iteracion'; 'x'; 'y'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = resolver_ode

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end